classdef Interaction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainTab                      matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        AddnewplotPanel              matlab.ui.container.Panel
        MaxPoolSizeEditFieldLabel    matlab.ui.control.Label
        MaxPoolSizeEditField         matlab.ui.control.NumericEditField
        MinPoolSizeforRetestingEditFieldLabel  matlab.ui.control.Label
        MinPoolSizeforRetestingEditField  matlab.ui.control.NumericEditField
        AddButton                    matlab.ui.control.Button
        SplitFactorEditFieldLabel    matlab.ui.control.Label
        SplitFactorEditField         matlab.ui.control.NumericEditField
        RetestonContradictionSwitchLabel  matlab.ui.control.Label
        RetestonContradictionSwitch  matlab.ui.control.Switch
        LegendEntryEditFieldLabel    matlab.ui.control.Label
        LegendEntryEditField         matlab.ui.control.EditField
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
        UIAxes_6                     matlab.ui.control.UIAxes
        InputPanel                   matlab.ui.container.Panel
        ofGroupsEditFieldLabel       matlab.ui.control.Label
        ofGroupsEditField            matlab.ui.control.NumericEditField
        StartRestartButton           matlab.ui.control.Button
        interpolationstepsEditFieldLabel  matlab.ui.control.Label
        interpolationstepsEditField  matlab.ui.control.NumericEditField
        MinPrevalenceEditFieldLabel  matlab.ui.control.Label
        MinPrevalenceEditField       matlab.ui.control.NumericEditField
        MaxPrevalenceEditFieldLabel  matlab.ui.control.Label
        MaxPrevalenceEditField       matlab.ui.control.NumericEditField
        Switch_7                     matlab.ui.control.Switch
        Switch_8                     matlab.ui.control.Switch
        Image                        matlab.ui.control.Image
        Switch                       matlab.ui.control.Switch
        Switch_2                     matlab.ui.control.Switch
        Switch_3                     matlab.ui.control.Switch
        Switch_4                     matlab.ui.control.Switch
        Switch_5                     matlab.ui.control.Switch
        Switch_6                     matlab.ui.control.Switch
        UIAxes2                      matlab.ui.control.UIAxes
        EfficiencyvsAverageTab       matlab.ui.container.Tab
        UIAxes_7                     matlab.ui.control.UIAxes
        UIAxes_8                     matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        SensitivityvsSpecificityTab  matlab.ui.container.Tab
        UIAxes_9                     matlab.ui.control.UIAxes
        UIAxes_10                    matlab.ui.control.UIAxes
        UIAxes2_3                    matlab.ui.control.UIAxes
        UIAxes_13                    matlab.ui.control.UIAxes
        PPVvsNPVTab                  matlab.ui.container.Tab
        UIAxes_11                    matlab.ui.control.UIAxes
        UIAxes_12                    matlab.ui.control.UIAxes
        UIAxes2_4                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks)
        end

        % Callback function
        function AmplitudeSliderValueChanging(app, event)
            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
        end

        % Callback function
        function MethodDropDownValueChanged(app, event)
            method = app.MethodDropDown.Value;
            app.Label.Text = app.MethodDropDown.Value;


        end

        % Callback function
        function RefreshButtonPushed(app, event)
            
        end

        % Button pushed function: StartRestartButton
        function StartRestartButtonPushed(app, event)
            if app.Switch_7.Value == "Linear"
                p_inf_sw = linspace(app.MinPrevalenceEditField.Value, app.MaxPrevalenceEditField.Value, app.interpolationstepsEditField.Value);
            else
                p_inf_sw = logspace(log10(app.MinPrevalenceEditField.Value), log10(app.MaxPrevalenceEditField.Value), app.interpolationstepsEditField.Value);
            end
            
            if app.Switch_8.Value == "PCR simple"
                diagnosis_list = {pcr_simple()};
            else
                diagnosis_list = {pcr_advanced()};
            end
            
            
            %[~, report] = Sweeper_API(p_inf_sw, iterations, strategy_list, diagnosis_list);
            pcr_sensitivity = diagnosis_list{1}.sensitivity;
            pcr_specificity = diagnosis_list{1}.specificity;
            pcr_tp = pcr_sensitivity * p_inf_sw;
            pcr_tn = pcr_specificity * (1-p_inf_sw);
            pcr_fp = (1-pcr_specificity) * (1-p_inf_sw);
            pcr_fn = (1-pcr_sensitivity) * p_inf_sw;
            
            eff_of_single_test = efficiency_of_a_single_test(p_inf_sw);
            
            pcr_ppv = pcr_tp./(pcr_tp+pcr_fp);
            pcr_npv = pcr_tn./(pcr_tn+pcr_fn);
            hold (app.UIAxes, "off");
            loglog(app.UIAxes, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes, "on");
            loglog(app.UIAxes, p_inf_sw,eff_of_single_test);
            hold (app.UIAxes_2, "off");
            loglog(app.UIAxes_2, p_inf_sw,eff_of_single_test);
            hold (app.UIAxes_2, "on");
            loglog(app.UIAxes_2, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_3, "off");
            semilogx(app.UIAxes_3, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_3, "on");
            semilogx(app.UIAxes_3, p_inf_sw,pcr_sensitivity*ones(size(p_inf_sw)));
            hold (app.UIAxes_4, "off");
            semilogx(app.UIAxes_4, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_4, "on");
            semilogx(app.UIAxes_4, p_inf_sw,pcr_specificity*ones(size(p_inf_sw)));
            hold (app.UIAxes_5, "off");
            semilogx(app.UIAxes_5, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_5, "on");
            semilogx(app.UIAxes_5, p_inf_sw, pcr_ppv);
            hold (app.UIAxes_6, "off");
            semilogx(app.UIAxes_6, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_6, "on");
            semilogx(app.UIAxes_6, p_inf_sw,pcr_npv);
            
            hold (app.UIAxes_7, "off");
            loglog(app.UIAxes_7, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_7, "on");
            loglog(app.UIAxes_7, p_inf_sw,eff_of_single_test);
            hold (app.UIAxes_8, "off");
            loglog(app.UIAxes_8, p_inf_sw,eff_of_single_test);
            hold (app.UIAxes_8, "on");
            loglog(app.UIAxes_8, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_9, "off");
            semilogx(app.UIAxes_9, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_9, "on");
            semilogx(app.UIAxes_9, p_inf_sw,pcr_sensitivity*ones(size(p_inf_sw)));
            hold (app.UIAxes_13, "off");
            semilogx(app.UIAxes_13, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_13, "on");
            semilogx(app.UIAxes_13, p_inf_sw,pcr_specificity*ones(size(p_inf_sw)));
            hold (app.UIAxes_11, "off");
            semilogx(app.UIAxes_11, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_11, "on");
            semilogx(app.UIAxes_11, p_inf_sw, pcr_ppv);
            hold (app.UIAxes_12, "off");
            semilogx(app.UIAxes_12, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_12, "on");
            semilogx(app.UIAxes_12, p_inf_sw,pcr_npv);
            
            global label_list
            global plotlist
            label_list = ["optimal";"individual test"];
            hold(app.UIAxes2, "off");
            plotlist = plot(app.UIAxes2, NaN, NaN);
            hold(app.UIAxes2, "on");
            plotlist(2)= plot(app.UIAxes2, NaN, NaN);
            legend(app.UIAxes2, plotlist, label_list);
            legend(app.UIAxes2_2, plotlist, label_list);
            legend(app.UIAxes2_3, plotlist, label_list);
            legend(app.UIAxes2_4, plotlist, label_list);
            
            
        end

        % Callback function
        function SwitchValueChanged2(app, event)
            value = app.Switch.Value;
            if value == "Log"
                app.UIAxes.XScale = 'log';
            elseif value == "Linear"
                app.UIAxes.XScale = 'linear';
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if value == "Log"
                app.UIAxes_2.XScale = 'log';
            elseif value == "Linear"
                app.UIAxes_2.XScale = 'linear';
            end
        end

        % Value changed function: MinPoolSizeforRetestingEditField
        function MinPoolSizeforRetestingEditFieldValueChanged(app, event)
            value = app.MinPoolSizeforRetestingEditField.Value;
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            if value == "Log"
                app.UIAxes_3.XScale = 'log';
            elseif value == "Linear"
                app.UIAxes_3.XScale = 'linear';
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            if value == "Log"
                app.UIAxes_4.XScale = 'log';
            elseif value == "Linear"
                app.UIAxes_4.XScale = 'linear';
            end
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            if value == "Log"
                app.UIAxes_5.XScale = 'log';
            elseif value == "Linear"
                app.UIAxes_5.XScale = 'linear';
            end
        end

        % Callback function
        function Switch_6ValueChanged(app, event)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged3(app, event)
            value = app.Switch.Value;
            if value == "Log"
                app.UIAxes.XScale = 'log';
            elseif value == "Linear"
                app.UIAxes.XScale = 'linear';
            end
        end

        % Callback function
        function ClearButtonPushed(app, event)
            strategy_CoSplit;
            Sweeper;
            plotit;
            pcr_tp = pcr_sensitivity * p_inf_sw;
            pcr_tn = pcr_specificity * (1-p_inf_sw);
            pcr_fp = (1-pcr_specificity) * (1-p_inf_sw);
            pcr_fn = (1-pcr_sensitivity) * p_inf_sw;
            
            pcr_ppv = pcr_tp./(pcr_tp+pcr_fp);
            pcr_npv = pcr_tn./(pcr_tn+pcr_fn);
            hold (app.UIAxes, "off");
            loglog(app.UIAxes, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes, "on");
            loglog(app.UIAxes, p_inf_sw,eff_of_single_test);
            hold (app.UIAxes_2, "off");
            loglog(app.UIAxes_2, p_inf_sw,eff_of_single_test);
            hold (app.UIAxes_2, "on");
            loglog(app.UIAxes_2, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_3, "off");
            semilogx(app.UIAxes_3, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_3, "on");
            semilogx(app.UIAxes_3, p_inf_sw,pcr_sensitivity*ones(size(p_inf_sw)));
            hold (app.UIAxes_4, "off");
            semilogx(app.UIAxes_4, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_4, "on");
            semilogx(app.UIAxes_4, p_inf_sw,pcr_specificity*ones(size(p_inf_sw)));
            hold (app.UIAxes_5, "off");
            semilogx(app.UIAxes_5, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_5, "on");
            semilogx(app.UIAxes_5, p_inf_sw, pcr_ppv);
            hold (app.UIAxes_6, "off");
            semilogx(app.UIAxes_6, p_inf_sw,ones(size(p_inf_sw)));
            hold (app.UIAxes_6, "on");
            semilogx(app.UIAxes_6, p_inf_sw,pcr_npv);
            label_list = ["optimal";"individual test"];
            hold(app.UIAxes2, "off");
            plotlist = plot(app.UIAxes2, NaN, NaN);
            hold(app.UIAxes2, "on");
            plotlist(2)= plot(app.UIAxes2, NaN, NaN);
            legend(app.UIAxes2, plotlist, label_list);
            legend(app.UIAxes2_2, plotlist, label_list);
            legend(app.UIAxes2_3, plotlist, label_list);
            legend(app.UIAxes2_4, plotlist, label_list);
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged2(app, event)
            value = app.Switch_6.Value;
            if value == "Log"
                app.UIAxes_6.XScale = 'log';
            elseif value == "Linear"
                app.UIAxes_6.XScale = 'linear';
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.Switch_7.Value == "Linear"
                p_inf_sw = linspace(app.MinPrevalenceEditField.Value, app.MaxPrevalenceEditField.Value, app.interpolationstepsEditField.Value);
            else
                p_inf_sw = logspace(log10(app.MinPrevalenceEditField.Value), log10(app.MaxPrevalenceEditField.Value), app.interpolationstepsEditField.Value);
            end
            strategy_temp = strategy_CoSplit();
            strategy_temp.max_pool_size = app.MaxPoolSizeEditField.Value;
            strategy_temp.split_factor = app.SplitFactorEditField.Value;
            strategy_temp.min_pool_size_for_retesting = app.MinPoolSizeforRetestingEditField.Value;
            if app.RetestonContradictionSwitch.Value == "Off"
                strategy_temp.retesting_if_counterintuitive = false;
            elseif app.RetestonContradictionSwitch.Value == "On"
                strategy_temp.retesting_if_counterintuitive = true;
            end
            strategy_list = {strategy_temp};
            
            if app.Switch_8.Value == "PCR simple"
                diagnosis_list = {pcr_simple()};
            else
                diagnosis_list = {pcr_advanced()};
            end
            
            iterations = app.ofGroupsEditField.Value;
            
            
            [~, report] = Sweeper_API(p_inf_sw, iterations, strategy_list, diagnosis_list);
            pcr_sensitivity = diagnosis_list{1}.sensitivity;
            pcr_specificity = diagnosis_list{1}.specificity;
            pcr_tp = pcr_sensitivity * p_inf_sw;
            pcr_tn = pcr_specificity * (1-p_inf_sw);
            pcr_fp = (1-pcr_specificity) * (1-p_inf_sw);
            pcr_fn = (1-pcr_sensitivity) * p_inf_sw;
            
            eff_of_single_test = efficiency_of_a_single_test(p_inf_sw);
            
            pcr_ppv = pcr_tp./(pcr_tp+pcr_fp);
            pcr_npv = pcr_tn./(pcr_tn+pcr_fn);
            hold (app.UIAxes, "on");
            loglog(app.UIAxes,p_inf_sw, report.efficiency_strategy);
            
            hold (app.UIAxes_2, "on");
            loglog(app.UIAxes_2, p_inf_sw,report.num_tests_per_patient_mean);
            
            hold (app.UIAxes_3, "on");
            semilogx(app.UIAxes_3, p_inf_sw,report.sensitivity);
            
            
            hold (app.UIAxes_4, "on");
            semilogx(app.UIAxes_4, p_inf_sw,report.specificity);
            
            hold (app.UIAxes_5, "on");
            semilogx(app.UIAxes_5, p_inf_sw, report.ppv);
            
            hold (app.UIAxes_6, "on");
            semilogx(app.UIAxes_6, p_inf_sw, report.npv);
            
            hold (app.UIAxes_7, "on");
            loglog(app.UIAxes_7,p_inf_sw, report.efficiency_strategy);
            
            hold (app.UIAxes_8, "on");
            loglog(app.UIAxes_8, p_inf_sw,report.num_tests_per_patient_mean);
            
            hold (app.UIAxes_9, "on");
            semilogx(app.UIAxes_9, p_inf_sw,report.sensitivity);
            
            
            hold (app.UIAxes_13, "on");
            semilogx(app.UIAxes_13, p_inf_sw,report.specificity);
            
            hold (app.UIAxes_11, "on");
            semilogx(app.UIAxes_11, p_inf_sw, report.ppv);
            
            hold (app.UIAxes_12, "on");
            semilogx(app.UIAxes_12, p_inf_sw, report.npv);
            
            name_label = app.LegendEntryEditField.Value;
            global label_list
            global plotlist
            label_list = [label_list;name_label];
            %loglog(app.UIAxes2, p_inf_sw,ones(size(p_inf_sw)));
            
            plotlist(end+1)= plot(app.UIAxes2, NaN, NaN);
            legend(app.UIAxes2, plotlist, label_list);
            legend(app.UIAxes2_2, plotlist, label_list);
            legend(app.UIAxes2_3, plotlist, label_list);
            legend(app.UIAxes2_4, plotlist, label_list);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes = uiaxes(app.MainTab);
            title(app.UIAxes, 'Informational Efficiency')
            xlabel(app.UIAxes, 'Prevalence')
            ylabel(app.UIAxes, 'Efficiency')
            app.UIAxes.XLim = [-10000 10000];
            app.UIAxes.XScale = 'log';
            app.AddnewplotPanel = uipanel(app.MainTab);
            app.AddnewplotPanel.Title = 'Add new plot';
            app.MaxPoolSizeEditFieldLabel = uilabel(app.AddnewplotPanel);
            app.MaxPoolSizeEditFieldLabel.Text = 'Max. Pool Size';
            app.MaxPoolSizeEditField = uieditfield(app.AddnewplotPanel, 'numeric');
            app.MaxPoolSizeEditField.Limits = [1 Inf];
            app.MaxPoolSizeEditField.RoundFractionalValues = 'on';
            app.MaxPoolSizeEditField.ValueDisplayFormat = '%.0f';
            app.MaxPoolSizeEditField.Value = 128;
            app.MinPoolSizeforRetestingEditFieldLabel = uilabel(app.AddnewplotPanel);
            app.MinPoolSizeforRetestingEditFieldLabel.Text = 'Min. Pool Size for Retesting';
            app.MinPoolSizeforRetestingEditField = uieditfield(app.AddnewplotPanel, 'numeric');
            app.MinPoolSizeforRetestingEditField.Limits = [1 Inf];
            app.MinPoolSizeforRetestingEditField.RoundFractionalValues = 'on';
            app.MinPoolSizeforRetestingEditField.ValueDisplayFormat = '%.0f';
            app.MinPoolSizeforRetestingEditField.ValueChangedFcn = createCallbackFcn(app, @MinPoolSizeforRetestingEditFieldValueChanged, true);
            app.MinPoolSizeforRetestingEditField.Value = 1;
            app.AddButton = uibutton(app.AddnewplotPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SplitFactorEditFieldLabel = uilabel(app.AddnewplotPanel);
            app.SplitFactorEditFieldLabel.Text = 'Split Factor';
            app.SplitFactorEditField = uieditfield(app.AddnewplotPanel, 'numeric');
            app.SplitFactorEditField.Limits = [2 Inf];
            app.SplitFactorEditField.RoundFractionalValues = 'on';
            app.SplitFactorEditField.ValueDisplayFormat = '%.0f';
            app.SplitFactorEditField.Value = 2;
            app.RetestonContradictionSwitchLabel = uilabel(app.AddnewplotPanel);
            app.RetestonContradictionSwitchLabel.Text = 'Retest on Contradiction';
            app.RetestonContradictionSwitch = uiswitch(app.AddnewplotPanel, 'slider');
            app.LegendEntryEditFieldLabel = uilabel(app.AddnewplotPanel);
            app.LegendEntryEditFieldLabel.Text = 'Legend Entry';
            app.LegendEntryEditField = uieditfield(app.AddnewplotPanel, 'text');
            app.UIAxes_2 = uiaxes(app.MainTab);
            title(app.UIAxes_2, 'Av. # tests per Person')
            xlabel(app.UIAxes_2, 'Prevalence')
            ylabel(app.UIAxes_2, 'Av. # tests per person')
            app.UIAxes_2.XLim = [-10000 10000];
            app.UIAxes_2.XScale = 'log';
            app.UIAxes_3 = uiaxes(app.MainTab);
            title(app.UIAxes_3, 'Sensitivity TP/(TP+FN)')
            xlabel(app.UIAxes_3, 'Prevalence')
            ylabel(app.UIAxes_3, 'Sensitivity')
            app.UIAxes_3.XLim = [-10000 10000];
            app.UIAxes_3.XScale = 'log';
            app.UIAxes_4 = uiaxes(app.MainTab);
            title(app.UIAxes_4, 'Specificity TN/(TN+FP)')
            xlabel(app.UIAxes_4, 'Prevalence')
            ylabel(app.UIAxes_4, 'Specificity')
            app.UIAxes_4.XLim = [-10000 10000];
            app.UIAxes_4.XScale = 'log';
            app.UIAxes_5 = uiaxes(app.MainTab);
            title(app.UIAxes_5, 'Positive predictive Value')
            xlabel(app.UIAxes_5, 'Prevalence')
            ylabel(app.UIAxes_5, 'PPV TP/(TP+FP)')
            app.UIAxes_5.XLim = [-10000 10000];
            app.UIAxes_5.XScale = 'log';
            app.UIAxes_6 = uiaxes(app.MainTab);
            title(app.UIAxes_6, 'Negative predictive Value')
            xlabel(app.UIAxes_6, 'Prevalence')
            ylabel(app.UIAxes_6, 'NPV NP/(TN+FN)')
            app.UIAxes_6.XLim = [-10000 10000];
            app.UIAxes_6.XScale = 'log';
            app.InputPanel = uipanel(app.MainTab);
            app.InputPanel.Title = 'Input';
            app.ofGroupsEditFieldLabel = uilabel(app.InputPanel);
            app.ofGroupsEditFieldLabel.Text = '# of Groups';
            app.ofGroupsEditField = uieditfield(app.InputPanel, 'numeric');
            app.ofGroupsEditField.Limits = [1 Inf];
            app.ofGroupsEditField.RoundFractionalValues = 'on';
            app.ofGroupsEditField.ValueDisplayFormat = '%.0f';
            app.ofGroupsEditField.Value = 10000;
            app.StartRestartButton = uibutton(app.InputPanel, 'push');
            app.StartRestartButton.ButtonPushedFcn = createCallbackFcn(app, @StartRestartButtonPushed, true);
            app.StartRestartButton.Text = 'Start/Restart';
            app.interpolationstepsEditFieldLabel = uilabel(app.InputPanel);
            app.interpolationstepsEditFieldLabel.Text = '# interpolation steps';
            app.interpolationstepsEditField = uieditfield(app.InputPanel, 'numeric');
            app.interpolationstepsEditField.Value = 1000;
            app.MinPrevalenceEditFieldLabel = uilabel(app.InputPanel);
            app.MinPrevalenceEditFieldLabel.Text = 'Min. Prevalence';
            app.MinPrevalenceEditField = uieditfield(app.InputPanel, 'numeric');
            app.MinPrevalenceEditField.Limits = [0 0.01];
            app.MinPrevalenceEditField.ValueDisplayFormat = '%3.e';
            app.MinPrevalenceEditField.Value = 0.0001;
            app.MaxPrevalenceEditFieldLabel = uilabel(app.InputPanel);
            app.MaxPrevalenceEditFieldLabel.Text = 'Max. Prevalence';
            app.MaxPrevalenceEditField = uieditfield(app.InputPanel, 'numeric');
            app.MaxPrevalenceEditField.Limits = [0.01 1];
            app.MaxPrevalenceEditField.ValueDisplayFormat = '%3.e';
            app.MaxPrevalenceEditField.Value = 0.2;
            app.Switch_7 = uiswitch(app.InputPanel, 'slider');
            app.Switch_7.Items = {'Log', 'Linear'};
            app.Switch_7.Value = 'Log';
            app.Switch_8 = uiswitch(app.InputPanel, 'slider');
            app.Switch_8.Items = {'PCR simple', 'PCR advanced'};
            app.Switch_8.Value = 'PCR simple';
            app.Image = uiimage(app.MainTab);
            app.Image.ImageSource = 'LogoApp.png';
            app.Switch = uiswitch(app.MainTab, 'slider');
            app.Switch.Items = {'Log', 'Linear'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged3, true);
            app.Switch.Value = 'Log';
            app.Switch_2 = uiswitch(app.MainTab, 'slider');
            app.Switch_2.Items = {'Log', 'Linear'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Log';
            app.Switch_3 = uiswitch(app.MainTab, 'slider');
            app.Switch_3.Items = {'Log', 'Linear'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'Log';
            app.Switch_4 = uiswitch(app.MainTab, 'slider');
            app.Switch_4.Items = {'Log', 'Linear'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'Log';
            app.Switch_5 = uiswitch(app.MainTab, 'slider');
            app.Switch_5.Items = {'Log', 'Linear'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = 'Log';
            app.Switch_6 = uiswitch(app.MainTab, 'slider');
            app.Switch_6.Items = {'Log', 'Linear'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged2, true);
            app.Switch_6.Value = 'Log';
            app.UIAxes2 = uiaxes(app.MainTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.EfficiencyvsAverageTab = uitab(app.TabGroup);
            app.EfficiencyvsAverageTab.Title = 'Efficiency vs. Average';
            app.UIAxes_7 = uiaxes(app.EfficiencyvsAverageTab);
            title(app.UIAxes_7, 'Informational Efficiency')
            xlabel(app.UIAxes_7, 'Prevalence')
            ylabel(app.UIAxes_7, 'Efficiency')
            app.UIAxes_7.XLim = [-10000 10000];
            app.UIAxes_7.XScale = 'log';
            app.UIAxes_8 = uiaxes(app.EfficiencyvsAverageTab);
            title(app.UIAxes_8, 'Av. # tests per Person')
            xlabel(app.UIAxes_8, 'Prevalence')
            ylabel(app.UIAxes_8, 'Av. # tests per person')
            app.UIAxes_8.XLim = [-10000 10000];
            app.UIAxes_8.XScale = 'log';
            app.UIAxes2_2 = uiaxes(app.EfficiencyvsAverageTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.SensitivityvsSpecificityTab = uitab(app.TabGroup);
            app.SensitivityvsSpecificityTab.Title = 'Sensitivity vs. Specificity';
            app.UIAxes_9 = uiaxes(app.SensitivityvsSpecificityTab);
            title(app.UIAxes_9, 'Sensitivity TP/(TP+FN)')
            xlabel(app.UIAxes_9, 'Prevalence')
            ylabel(app.UIAxes_9, 'Sensitivity')
            app.UIAxes_9.XLim = [-10000 10000];
            app.UIAxes_9.XScale = 'log';
            app.UIAxes_10 = uiaxes(app.SensitivityvsSpecificityTab);
            title(app.UIAxes_10, 'Specificity TN/(TN+FP)')
            xlabel(app.UIAxes_10, 'Prevalence')
            ylabel(app.UIAxes_10, 'Specificity')
            app.UIAxes_10.XLim = [-10000 10000];
            app.UIAxes_10.XScale = 'log';
            app.UIAxes2_3 = uiaxes(app.SensitivityvsSpecificityTab);
            title(app.UIAxes2_3, '')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, '')
            app.UIAxes_13 = uiaxes(app.SensitivityvsSpecificityTab);
            title(app.UIAxes_13, 'Specificity TN/(TN+FP)')
            xlabel(app.UIAxes_13, 'Prevalence')
            ylabel(app.UIAxes_13, 'Specificity')
            app.UIAxes_13.XLim = [-10000 10000];
            app.UIAxes_13.XScale = 'log';
            app.PPVvsNPVTab = uitab(app.TabGroup);
            app.PPVvsNPVTab.Title = 'PPV vs. NPV';
            app.UIAxes_11 = uiaxes(app.PPVvsNPVTab);
            title(app.UIAxes_11, 'Positive predictive Value')
            xlabel(app.UIAxes_11, 'Prevalence')
            ylabel(app.UIAxes_11, 'PPV TP/(TP+FP)')
            app.UIAxes_11.XLim = [-10000 10000];
            app.UIAxes_11.XScale = 'log';
            app.UIAxes_12 = uiaxes(app.PPVvsNPVTab);
            title(app.UIAxes_12, 'Negative predictive Value')
            xlabel(app.UIAxes_12, 'Prevalence')
            ylabel(app.UIAxes_12, 'NPV NP/(TN+FN)')
            app.UIAxes_12.XLim = [-10000 10000];
            app.UIAxes_12.XScale = 'log';
            app.UIAxes2_4 = uiaxes(app.PPVvsNPVTab);
            title(app.UIAxes2_4, '')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interaction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end